classdef C22037039_COURSEWORK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HideRevealAllIntermediateConstructionsButton  matlab.ui.control.Button
        HideRevealLinesButton   matlab.ui.control.Button
        HideRevealPointsButton  matlab.ui.control.Button
        RunConstructionButton   matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pointsArray % Array of all points plotted
        pointsVisible % Boolean for if points are visible
    end

    methods (Access = private)
        
        function displayArray(app)
            disp(app.pointsArray);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Get the mouse click location
            clickCoords = app.UIAxes.CurrentPoint;
            x = clickCoords(1, 1);
            y = clickCoords(1, 2);
            
            % Plot the point on the axes
            hold(app.UIAxes, 'on'); % Retain previous plots
            plot(app.UIAxes, x, y, 'ro', 'MarkerSize', 4, 'MarkerFaceColor', 'g'); 

            app.pointsArray = [app.pointsArray; [x,y]]; % Array is collumn wise for better formatting
            app.pointsVisible = true;
        end

        % Button pushed function: HideRevealPointsButton
        function HideRevealPointsButtonPushed(app, event)

           if app.pointsVisible
               set( findobj(app.UIAxes.Children), 'Visible', 'off');
               app.pointsVisible = false;
           else  
               set( findobj(app.UIAxes.Children), 'Visible', 'on');
               app.pointsVisible = true;
           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PLOTTING CIRCLES')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.XTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RunConstructionButton = uibutton(app.UIFigure, 'push');
            app.RunConstructionButton.Text = 'Run Construction';
            app.HideRevealPointsButton = uibutton(app.UIFigure, 'push');
            app.HideRevealPointsButton.ButtonPushedFcn = createCallbackFcn(app, @HideRevealPointsButtonPushed, true);
            app.HideRevealPointsButton.Text = 'Hide/Reveal Points';
            app.HideRevealLinesButton = uibutton(app.UIFigure, 'push');
            app.HideRevealLinesButton.Text = 'Hide/Reveal Lines';
            app.HideRevealAllIntermediateConstructionsButton = uibutton(app.UIFigure, 'push');
            app.HideRevealAllIntermediateConstructionsButton.Text = {'Hide/Reveal '; 'All Intermediate Constructions'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = C22037039_COURSEWORK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end